classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Label_9                        matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        ConfiguraesdofiltroLabel       matlab.ui.control.Label
        ConfiguraesdajanelaLabel       matlab.ui.control.Label
        TipoDropDownLabel              matlab.ui.control.Label
        tipoFiltro                     matlab.ui.control.DropDown
        tipoJanela                     matlab.ui.control.DropDown
        FreqPassagemLabel              matlab.ui.control.Label
        freqdepassagem                 matlab.ui.control.NumericEditField
        GerarButton                    matlab.ui.control.Button
        FreqdeamostHzLabel             matlab.ui.control.Label
        freqAmostragem                 matlab.ui.control.NumericEditField
        SelecaoautomaticadejanelaCheckBox  matlab.ui.control.CheckBox
        FreqRejeicaoLabel              matlab.ui.control.Label
        freqderejeicao                 matlab.ui.control.NumericEditField
        TipoFiltroLabel                matlab.ui.control.Label
        RipplemxnabandadepassagemdBLabel  matlab.ui.control.Label
        rippleBandaPassagem            matlab.ui.control.NumericEditField
        AtenuaonabandaderejeiodBLabel  matlab.ui.control.Label
        atenuacaoBandaRejeicao         matlab.ui.control.NumericEditField
        FreqPassagemLabel2             matlab.ui.control.Label
        FreqPassagemSup                matlab.ui.control.NumericEditField
        FreqRejeicaoLabel2             matlab.ui.control.Label
        FreqRejeicaoSup                matlab.ui.control.NumericEditField
        RightPanel                     matlab.ui.container.Panel
        FiltroprticoLabel              matlab.ui.control.Label
        JanelaLabel                    matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        TempoTabP                      matlab.ui.container.Tab
        TempoPratico                   matlab.ui.control.UIAxes
        MagnitudeTab                   matlab.ui.container.Tab
        MagnitudeFiltro                matlab.ui.control.UIAxes
        FaseTab                        matlab.ui.container.Tab
        FaseFiltro                     matlab.ui.control.UIAxes
        ResultadoLabel                 matlab.ui.control.Label
        LarguradajanelaLabel           matlab.ui.control.Label
        larguraJanela                  matlab.ui.control.NumericEditField
        JanelautilizadaLabel           matlab.ui.control.Label
        JanelaUtilizadaText            matlab.ui.control.EditField
        CoeficientesdofiltroLabel      matlab.ui.control.Label
        OrdemEditFieldLabel            matlab.ui.control.Label
        ordemFiltro                    matlab.ui.control.NumericEditField
        TabGroup_2                     matlab.ui.container.TabGroup
        MagnitudeTab_2                 matlab.ui.container.Tab
        MagnitudeJanela                matlab.ui.control.UIAxes
        FaseTab_2                      matlab.ui.container.Tab
        FaseJanela                     matlab.ui.control.UIAxes
        coefsFiltro                    matlab.ui.control.TextArea
        HTML                           matlab.ui.control.HTML
        HTML_2                         matlab.ui.control.HTML
        HTML_3                         matlab.ui.control.HTML
        TabGroup_3                     matlab.ui.container.TabGroup
        TempoTab                       matlab.ui.container.Tab
        TempoIdeal                     matlab.ui.control.UIAxes
        MagnitudeTab_3                 matlab.ui.container.Tab
        MagnitudeIdeal                 matlab.ui.control.UIAxes
        FaseTab_3                      matlab.ui.container.Tab
        FaseIdeal                      matlab.ui.control.UIAxes
        FiltroidealLabel               matlab.ui.control.Label
        HTML_4                         matlab.ui.control.HTML
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        RETANGULAR_STOPBATTENUATION = 21;           % Atenuação da jan. retangular na banda de rejeição  (em dB)
        BARLETT_STOPBATTENUATION = 25;              % Atenuação da jan. de Barlett na banda de rejeição  (em dB)
        HANN_STOPBATTENUATION = 44;                 % Atenuação da jan. de Hann na banda de rejeição     (em dB)
        HAMMING_STOPBATTENUATION = 53;              % Atenuação da jan. de Hamming na banda de rejeição  (em dB)
        BLACKMAN_STOPBATTENUATION = 74;             % Atenuação da jan. de Blackman na banda de rejeição (em dB)
        
        RETANGULAR_PASSBRIPPLE = 0.7416;            % Oscilação da jan. retangular na banda de passagem  (em dB)
        BARLETT_PASSBRIPPLE = 0.4237;               % Oscilação da jan. de Barlett na banda de passagem  (em dB)
        HANN_PASSBRIPPLE = 0.0546;                  % Oscilação da jan. de Hann na banda de passagem     (em dB)
        HAMMING_PASSBRIPPLE = 0.0194;               % Oscilação da jan. de Hamming na banda de passagem  (em dB)
        BLACKMAN_PASSBRIPPLE = 0.0017;              % Oscilação da jan. de Blackman na banda de passagem (em dB)
    end
    
    methods (Access = private)      
        % Plota o espectro de magnitude do sinal informado
        function magPlot(~, signal, fftLength, plotSpot, fs)
            A = fft(signal, fftLength);             % Cálculo da FFT do sinal usando uma quantidade fftLength pontos
            mag = abs(fftshift(A));                 % Obtenção da magnitude do sinal
            freq = -fs/2 : fs/(length(A)-1) : fs/2; % Eixo da frequência para plotagem do sinal
            response = 20 * log10(mag);             % Transformando a magnitude do sinal para decibéis
            plot(plotSpot, freq, response);         % Plotagem da magnitude do sinal
        end
        
        % Plota o espectro de fase do sinal informado
        function phasePlot(~, signal, fftLength, plotSpot)
            A = fft(signal, fftLength);             % Cálculo da FFT do sinal usando uma quantidade fftLength pontos
            phase = angle(A);                       % Obtenção da fase do sinal
            plot(plotSpot, phase);                  % Plotagem da fase do sinal
        end    
        
        %Função que calcula o tamanho da janela escolhida
        function winSize = windowSize(~, idWindow, fpass, fstop, fsamp)
            wpass = 2 * pi * fpass;                 % Frequência de passagem           (em rad/s)
            wstop = 2 * pi * fstop;                 % Frequência de rejeição           (em rad/s)          
            wsamp = 2 * pi * fsamp;                 % Frequência de amostragem         (em rad/s)
            delta_w = abs((wstop - wpass)/wsamp);   % Largura de transição normalizada (em rad/s)
            if idWindow == 1                        % Caso a janela selecionada seja a retangular
                M = ceil(((4 * pi) - delta_w) ...   % Tamanho da janela retangular 
                    / delta_w);
            elseif idWindow == 2 || ...             % Caso a janela selecionada seja Bartlett, Hann ou Hamming
                    idWindow == 3 || idWindow == 4
                M = ceil((8 * pi) / delta_w);       % Tamanho da janela selecionada
            else                                    % Caso a janela selecionada seja a de Blackman
                M = ceil((12 * pi) / delta_w);      % Tamanho da janela
            end          
            winSize = M;                            % Define tamanho da janela para ser retornado
        end
        
        %Função que a partir dos parâmentro recebidos devolve a janela selecionada 
        function window = getWindow(app, idWindow, windowSize)
            if idWindow == 1                        % Caso a janela selecionada seja a retangular
                window = rectwin(windowSize);       % Cria uma janela retangular
                app.JanelaUtilizadaText.Value ...   % Define o nome da janela usada
                    = "Retangular";
            elseif idWindow == 2                    % Caso a janela selecionada seja a de Bartlett
                window = bartlett(windowSize);      % Cria uma janela de Bartlett
                app.JanelaUtilizadaText.Value ...   % Define o nome da janela usada
                    = "Bartlett";
            elseif idWindow == 3                    % Caso a janela selecionada seja de Hann
                window = hann(windowSize);          % Cria uma janela de Hann
                app.JanelaUtilizadaText.Value ...   % Define o nome da janela usada
                    = "Hann";
            elseif idWindow == 4                    % Caso a janela selecionada seja a de Hamming
                window = hamming(windowSize);       % Cria uma janela de Hamming
                app.JanelaUtilizadaText.Value ...   % Define o nome da janela usada
                    = "Hamming";
            else                                    % Caso a janela selecionada seja a de Blackman
                window = blackman(windowSize);      % Cria uma janela de Blackman
                app.JanelaUtilizadaText.Value ...   % Define o nome da janela usada
                    = "Blackman";
            end
        end
        
        % Função que a partir das oscilações na banda de passagem e da atenuação na banda de rejeição analisa quais janelas possíveis podem ser utilizadas
        function possibleWindows = refreshPossibleWindows(app)
            if app.rippleBandaPassagem.Value == 0 || app.atenuacaoBandaRejeicao.Value == 0
                    app.tipoJanela.Items = ["Retangular", "Barlett", "Hann", "Hamming", "Blackman"];
                else
                    aux = 1;
                    if  app.RETANGULAR_STOPBATTENUATION  >= app.atenuacaoBandaRejeicao.Value...
                        && app.RETANGULAR_PASSBRIPPLE    <= app.rippleBandaPassagem.Value
                            possibleWindows(aux) = "Retangular";
                            aux = aux + 1;
                    end
                    if app.BARLETT_STOPBATTENUATION   >= app.atenuacaoBandaRejeicao.Value...
                        && app.BARLETT_PASSBRIPPLE    <= app.rippleBandaPassagem.Value
                            possibleWindows(aux) = "Bartlett";
                            aux = aux + 1;
                    end
                    if app.HANN_STOPBATTENUATION      >= app.atenuacaoBandaRejeicao.Value...
                        && app.HANN_PASSBRIPPLE       <= app.rippleBandaPassagem.Value
                            possibleWindows(aux) = "Hann";
                            aux = aux + 1;
                    end
                    if app.HAMMING_STOPBATTENUATION   >= app.atenuacaoBandaRejeicao.Value...
                        && app.HAMMING_PASSBRIPPLE    <= app.rippleBandaPassagem.Value
                            possibleWindows(aux) = "Hamming";
                            aux = aux + 1;
                    end
                    if app.BLACKMAN_STOPBATTENUATION  >= app.atenuacaoBandaRejeicao.Value...
                        && app.BLACKMAN_PASSBRIPPLE   <= app.rippleBandaPassagem.Value
                            possibleWindows(aux) = "Blackman";
                    end
                    if exist('possibleWindows', 'var') == 0
                        possibleWindows(aux) = "-";                    
                    end
                    app.tipoJanela.Items = possibleWindows;
                    app.tipoJanela.Value = possibleWindows(1);
            end
        end
        
        % Função que analisa os valores dos campos preenchidos pelo usuário e caso tenha algum erro cria um popup para informá-lo sobre o usuário
        function valide = validateFields(app)
            valide = true;
            if app.tipoFiltro.Value == "Passa-baixas"
                if app.freqdepassagem.Value > app.freqderejeicao.Value
                    msgbox('Para a construção de um filtro passa-baixas, a frequência de passagem deve ser inferior a frequência de rejeição.', 'Erro', 'error');
                    valide = false;
                end
            elseif app.tipoFiltro.Value == "Passa-altas"
                if app.freqdepassagem.Value < app.freqderejeicao.Value
                    msgbox('Para a construção de um filtro passa-altas, a frequência de passagem deve ser superior a frequência de rejeição.', 'Erro', 'error');
                    valide = false;
                end
            elseif app.tipoFiltro.Value == "Passa-faixas"
                if app.freqdepassagem.Value < app.freqderejeicao.Value
                    msgbox('Para a construção de um filtro passa-faixa, a frequência de passagem inferior deve ser superior a frequência de rejeição inferior.', 'Erro', 'error');
                    valide = false;
                elseif app.FreqPassagemSup.Value < app.freqdepassagem.Value
                    msgbox('Para a construção de um filtro passa-faixa, a frequência de passagem superior deve ser superior a frequência de passagem inferior.', 'Erro', 'error');
                    valide = false;
                elseif app.FreqRejeicaoSup.Value < app.FreqPassagemSup.Value
                    msgbox('Para a construção de um filtro passa-faixa, a frequência de rejeição superior deve ser superior a frequência de passagem superior.', 'Erro', 'error');
                    valide = false;
                end
            elseif app.tipoFiltro.Value == "Rejeita faixa"
                if app.freqdepassagem.Value > app.freqderejeicao.Value
                    msgbox('Para a construção de um filtro rejeita faixa, a frequência de passagem inferior deve ser inferior a frequência de rejeição inferior.', 'Erro', 'error');
                    valide = false;
                elseif app.freqderejeicao.Value > app.FreqRejeicaoSup.Value
                    msgbox('Para a construção de um filtro rejeita faixa, a frequência de rejeição superior deve ser superior a frequência de rejeição inferior.', 'Erro', 'error');
                    valide = false;
                elseif app.FreqRejeicaoSup.Value > app.FreqPassagemSup.Value
                    msgbox('Para a construção de um filtro rejeita faixa, a frequência de rejeição superior deve ser inferior a frequência de passagem superior.', 'Erro', 'error');
                    valide = false;
                end
            end
        end
        
        % Função que calcula o valor da sinc para o tipo de filtro dado,
        % para o valor de n recebido por parâmetro
        function sincF = sincFunction(~, tipoFiltro, n, digCutOffFreq, varargin)
            switch tipoFiltro                                      
                case 'Passa-baixas'
                    sincF = digCutOffFreq * sinc(digCutOffFreq * n);
                case 'Passa-altas'
                    if n == 0
                        sincF = 1 - digCutOffFreq;
                    else
                       sincF = -(digCutOffFreq * sinc(digCutOffFreq * n)); 
                    end
                case 'Rejeita faixa'
                    if n == 0
                        sincF = 1 - varargin{1} + digCutOffFreq;
                    else
                        sincF = digCutOffFreq * sinc(digCutOffFreq * n) - varargin{1} * sinc(varargin{1} * n);
                    end
                case 'Passa-faixas'
                    if n == 0
                        sincF = varargin{1} - digCutOffFreq;
                    else
                        sincF = varargin{1} * sinc(varargin{1} * n) - digCutOffFreq * sinc(digCutOffFreq * n);
                    end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GerarButton
        function GerarButtonPushed(app, event)
            if validateFields(app)
                %Caso o usuário não escolha a janeja utilizada, preenche com as possibilidades de janelas que podem ser usadas
                if app.SelecaoautomaticadejanelaCheckBox.Value == true
                    windowPossibilities = [...
                        app.RETANGULAR_STOPBATTENUATION  >= app.atenuacaoBandaRejeicao.Value...
                            & app.RETANGULAR_PASSBRIPPLE <= app.rippleBandaPassagem.Value ...
                        app.BARLETT_STOPBATTENUATION     >= app.atenuacaoBandaRejeicao.Value...
                            & app.BARLETT_PASSBRIPPLE    <= app.rippleBandaPassagem.Value ...
                        app.HANN_STOPBATTENUATION        >= app.atenuacaoBandaRejeicao.Value...
                            & app.HANN_PASSBRIPPLE       <= app.rippleBandaPassagem.Value ...
                        app.HAMMING_STOPBATTENUATION     >= app.atenuacaoBandaRejeicao.Value...
                            & app.HAMMING_PASSBRIPPLE    <= app.rippleBandaPassagem.Value ...
                        app.BLACKMAN_STOPBATTENUATION    >= app.atenuacaoBandaRejeicao.Value...
                            & app.BLACKMAN_PASSBRIPPLE   <= app.rippleBandaPassagem.Value
                    ];
                    aux = 1;
                    % A partir do vetor de possíveis janelas se calcula o tamanho de cada possível janela a ser utilizada
                    for i = 1 : length(windowPossibilities)
                        if windowPossibilities(i)
                            filterOrder(aux) = windowSize(app, i, ...
                                app.freqdepassagem.Value, app.freqderejeicao.Value, ...
                                app.freqAmostragem.Value) - 1;
                            if(rem(filterOrder(aux), 2) == 0)
                                filterOrder(aux) = filterOrder(aux) + 1;
                            end
                            aux = aux + 1;
                        end
                    end
                    % Seleciona a janela que vai gerar o filtro de menor ordem
                    [~, index] = min(filterOrder);
                    wdSize = filterOrder(index) + 1;
                    % Verificar se a janela é ímpar para corrigir
                    if(rem(wdSize, 2) == 0)
                        wdSize = wdSize + 1;
                        filterOrder(index) = filterOrder(index) + 1;
                    end
                    idx = find(windowPossibilities == true, index, 'first');
                    window = getWindow(app, idx, wdSize);
                    app.larguraJanela.Value = wdSize;
                    filtOrd = filterOrder(index);
                else  % Caso o usuário escolha a janela
                    if app.tipoJanela.Value == "Retangular"
                        idJan = 1;
                    elseif app.tipoJanela.Value == "Bartlett"
                        idJan = 2;
                    elseif app.tipoJanela.Value == "Hann"
                        idJan = 3;
                    elseif app.tipoJanela.Value == "Hamming"
                        idJan = 4;
                    else
                        idJan = 5;
                    end
                    % Calcula o tamanho da janela
                    wdSize = windowSize(app, idJan, app.freqdepassagem.Value, app.freqderejeicao.Value, app.freqAmostragem.Value);
                    % Caso o tamanho da janela seja par, força que seja ímpar      
                    if(rem(wdSize, 2) == 0)
                        wdSize = wdSize + 1;
                    end
                    % Coloca o tamaho da janela na GUI
                    app.larguraJanela.Value = wdSize;
                    % Obtém a janela a ser utilizada
                    window = getWindow(app, idJan, wdSize);
                    % Calcula a ordem do filtro com base no tamanho da janela
                    filtOrd = wdSize - 1;
                end
                % Plota a magnitude da janela a ser utilizada
                magPlot(app, window, 8192, app.MagnitudeJanela, app.freqAmostragem.Value);
                % Plota a fase da janela a ser utilizada
                phasePlot(app, window, 8192, app.FaseJanela);
                % Cálculo da frequência de corte
                fcutoff = (app.freqdepassagem.Value + app.freqderejeicao.Value) / 2;
                % Obtenção da frequência de corte em rad/s
                wcutoff = 2 * pi * fcutoff;
                % Obtenção da frequência de corte digital
                digitalCutOffFreq = (wcutoff / app.freqAmostragem.Value)/pi;
                M = (wdSize-1)/2;
                n = -M : M;
                % A depender do tipo de filtro define a função sinc ideal a ser utilizada
                if app.tipoFiltro.Value == "Passa-baixas" || app.tipoFiltro.Value == "Passa-altas"
                    fsinc = @(n) sincFunction(app, app.tipoFiltro.Value, n, digitalCutOffFreq);
                else
                    fcutoff2 = (app.FreqPassagemSup.Value + app.FreqRejeicaoSup.Value) / 2;
                    wcutoff2 = 2 * pi * fcutoff2;
                    digitalCutOffFreq2 = (wcutoff2/app.freqAmostragem.Value)/pi;
                    fsinc = @(n) sincFunction(app, app.tipoFiltro.Value, n, digitalCutOffFreq, digitalCutOffFreq2);
                end
                
                % Obtém os valores do filtro ideal
                for i = 1:length(n)
                    idealFilter(i) = fsinc(n(i));
                end
                
                % Plota a magnitude do filtro ideal
                magPlot(app, idealFilter, 8192, app.MagnitudeIdeal, app.freqAmostragem.Value);
                
                % Plota a fase do filtro ideal
                phasePlot(app, idealFilter, 8192, app.FaseIdeal);
                
                % Plota o filtro ideal no domínio da frequência
                stem(app.TempoIdeal, n, idealFilter);
                
                % Obtém o filtro janelado, janelando o filtro ideal
                windowedFilter = idealFilter .* window';
                
                % Obtém o filtro prático, aplicando um delay de M
                practicalFilter = [zeros(1,M) windowedFilter];
                n = -M : 2*M;
%                 practicalFilter = delayseq(windowedFilter', M)';
                
                % Plota o filtro prático no domínio do tempo
                stem(app.TempoPratico, n, practicalFilter);
                
                % Coloca na GUI os coeficientes do filtro ideal
                app.coefsFiltro.Value = num2str(practicalFilter);
                
                % Coloca na GUI a ordem do filtro
                app.ordemFiltro.Value = filtOrd;
               
                % Plota a magnitude do filtro prático
                magPlot(app, practicalFilter, 8192, app.MagnitudeFiltro, app.freqAmostragem.Value);
                
                % Plota a fase do filtro prático
                phasePlot(app, practicalFilter, 8192, app.FaseFiltro);
            end
        end

        % Value changed function: SelecaoautomaticadejanelaCheckBox
        function SelecaoautomaticadejanelaCheckBoxValueChanged(app, event)
            % Função que exibe o box para escolha de janela, caso o usuário
            % desmarque a opção de seleção automática, e esconde-o caso
            % contrário
            value = app.SelecaoautomaticadejanelaCheckBox.Value;
            if value == true
                app.tipoJanela.Enable = false;
                app.tipoJanela.Visible = false;
                app.TipoFiltroLabel.Visible = false;
                app.JanelautilizadaLabel.Visible = true;
                app.JanelaUtilizadaText.Visible = true;
            else
                refreshPossibleWindows(app);
                app.tipoJanela.Enable = true;
                app.tipoJanela.Visible = true;
                app.TipoFiltroLabel.Visible = true;
                app.JanelautilizadaLabel.Visible = false;
                app.JanelaUtilizadaText.Visible = false;
            end
        end

        % Value changed function: tipoFiltro
        function tipoFiltroValueChanged(app, event)
            % Dependendo do tipo do filtro, define quais campos de parâmetros aparecem para serem editados
            value = app.tipoFiltro.Value;
            if value == "Passa-baixas" || value == "Passa-altas"
                app.FreqPassagemLabel.Text = "Freq. de passagem (Hz)";
                app.FreqRejeicaoLabel.Text = "Freq. de rejeição (Hz)";
                app.FreqPassagemLabel2.Visible = false;
                app.FreqRejeicaoLabel2.Visible = false;
                app.FreqRejeicaoSup.Visible = false;
                app.FreqPassagemSup.Visible = false;
            elseif value == "Passa-faixas" || value == "Rejeita faixa"
                app.FreqPassagemLabel.Text = "Freq. de passagem"  + newline + "inferior (Hz)";
                app.FreqRejeicaoLabel.Text = "Freq. de rejeição"  + newline + "inferior (Hz)";
                app.FreqPassagemLabel2.Visible = true;
                app.FreqRejeicaoLabel2.Visible = true;
                app.FreqRejeicaoSup.Visible = true;
                app.FreqPassagemSup.Visible = true;
            end
            
        end

        % Value changed function: rippleBandaPassagem
        function rippleBandaPassagemValueChanged(app, event)
            % Checa se o valor informado para oscilação na banda de
            % passagem é suportado pelo programa
            if app.rippleBandaPassagem.Value < app.BLACKMAN_PASSBRIPPLE
                    msgbox('Este programa suporta oscilação mínima na banda de passagem de 0.0017dB.', 'Erro', 'error');
                    app.GerarButton.Enable = false;
            else
                app.GerarButton.Enable = true;
            end
            if app.SelecaoautomaticadejanelaCheckBox.Value == false
                refreshPossibleWindows(app);
            end
        end

        % Value changed function: atenuacaoBandaRejeicao
        function atenuacaoBandaRejeicaoValueChanged(app, event)
            % Checa se o valor informado para atenuação na banda de rejeição é suportado pelo programa
            if app.atenuacaoBandaRejeicao.Value > app.BLACKMAN_STOPBATTENUATION
                    msgbox('Este programa suporta atenuação máxima na banda de rejeição de 74dB.', 'Erro', 'error');
                    app.GerarButton.Enable = false;
            else
                app.GerarButton.Enable = true;
            end
            if app.SelecaoautomaticadejanelaCheckBox.Value == false
                refreshPossibleWindows(app);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {522, 522};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {295, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label_9 = uilabel(app.LeftPanel);
            app.Label_9.Text = '__________________________________________';
            app.Label_10 = uilabel(app.LeftPanel);
            app.Label_10.Text = '__________________________________________';
            app.ConfiguraesdofiltroLabel = uilabel(app.LeftPanel);
            app.ConfiguraesdofiltroLabel.Text = 'Configurações do filtro';
            app.ConfiguraesdajanelaLabel = uilabel(app.LeftPanel);
            app.ConfiguraesdajanelaLabel.Text = 'Configurações da janela';
            app.TipoDropDownLabel = uilabel(app.LeftPanel);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.tipoFiltro = uidropdown(app.LeftPanel);
            app.tipoFiltro.Items = {'Passa-baixas', 'Passa-altas', 'Passa-faixas', 'Rejeita faixa'};
            app.tipoFiltro.ValueChangedFcn = createCallbackFcn(app, @tipoFiltroValueChanged, true);
            app.tipoFiltro.Value = 'Passa-altas';
            app.tipoJanela = uidropdown(app.LeftPanel);
            app.tipoJanela.Items = {'Retangular', 'Bartlett', 'Hann', 'Hamming', 'Blackman'};
            app.tipoJanela.Enable = 'off';
            app.tipoJanela.Visible = 'off';
            app.tipoJanela.Value = 'Retangular';
            app.FreqPassagemLabel = uilabel(app.LeftPanel);
            app.FreqPassagemLabel.Text = 'Freq. de passagem (Hz)';
            app.freqdepassagem = uieditfield(app.LeftPanel, 'numeric');
            app.freqdepassagem.Limits = [0 Inf];
            app.freqdepassagem.Value = 2500;
            app.GerarButton = uibutton(app.LeftPanel, 'push');
            app.GerarButton.ButtonPushedFcn = createCallbackFcn(app, @GerarButtonPushed, true);
            app.GerarButton.Text = 'Gerar';
            app.FreqdeamostHzLabel = uilabel(app.LeftPanel);
            app.FreqdeamostHzLabel.Text = 'Freq. de amost. (Hz)';
            app.freqAmostragem = uieditfield(app.LeftPanel, 'numeric');
            app.freqAmostragem.Limits = [0 Inf];
            app.freqAmostragem.ValueDisplayFormat = '%.0f';
            app.freqAmostragem.Value = 7000;
            app.SelecaoautomaticadejanelaCheckBox = uicheckbox(app.LeftPanel);
            app.SelecaoautomaticadejanelaCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelecaoautomaticadejanelaCheckBoxValueChanged, true);
            app.SelecaoautomaticadejanelaCheckBox.Text = 'Seleção automática de janela';
            app.SelecaoautomaticadejanelaCheckBox.Value = true;
            app.FreqRejeicaoLabel = uilabel(app.LeftPanel);
            app.FreqRejeicaoLabel.Text = 'Freq. de rejeição (Hz)';
            app.freqderejeicao = uieditfield(app.LeftPanel, 'numeric');
            app.freqderejeicao.Limits = [0 Inf];
            app.freqderejeicao.Value = 1000;
            app.TipoFiltroLabel = uilabel(app.LeftPanel);
            app.TipoFiltroLabel.Visible = 'off';
            app.TipoFiltroLabel.Text = 'Tipo';
            app.RipplemxnabandadepassagemdBLabel = uilabel(app.LeftPanel);
            app.RipplemxnabandadepassagemdBLabel.Text = {'Ripple máx. na banda'; 'de passagem (dB)'};
            app.rippleBandaPassagem = uieditfield(app.LeftPanel, 'numeric');
            app.rippleBandaPassagem.Limits = [0 Inf];
            app.rippleBandaPassagem.ValueChangedFcn = createCallbackFcn(app, @rippleBandaPassagemValueChanged, true);
            app.rippleBandaPassagem.Value = 0.1;
            app.AtenuaonabandaderejeiodBLabel = uilabel(app.LeftPanel);
            app.AtenuaonabandaderejeiodBLabel.Text = {'Atenuação na banda'; 'de rejeição (dB)'};
            app.atenuacaoBandaRejeicao = uieditfield(app.LeftPanel, 'numeric');
            app.atenuacaoBandaRejeicao.Limits = [0 Inf];
            app.atenuacaoBandaRejeicao.ValueDisplayFormat = '%.0f';
            app.atenuacaoBandaRejeicao.ValueChangedFcn = createCallbackFcn(app, @atenuacaoBandaRejeicaoValueChanged, true);
            app.atenuacaoBandaRejeicao.Value = 40;
            app.FreqPassagemLabel2 = uilabel(app.LeftPanel);
            app.FreqPassagemLabel2.Visible = 'off';
            app.FreqPassagemLabel2.Text = {'Freq. de passagem '; 'superior (Hz)'};
            app.FreqPassagemSup = uieditfield(app.LeftPanel, 'numeric');
            app.FreqPassagemSup.Limits = [0 Inf];
            app.FreqPassagemSup.Visible = 'off';
            app.FreqPassagemSup.Value = 1000;
            app.FreqRejeicaoLabel2 = uilabel(app.LeftPanel);
            app.FreqRejeicaoLabel2.Visible = 'off';
            app.FreqRejeicaoLabel2.Text = {'Freq. de rejeição '; 'superior (Hz)'};
            app.FreqRejeicaoSup = uieditfield(app.LeftPanel, 'numeric');
            app.FreqRejeicaoSup.Limits = [0 Inf];
            app.FreqRejeicaoSup.Visible = 'off';
            app.FreqRejeicaoSup.Value = 3000;
            app.RightPanel = uipanel(app.GridLayout);
            app.FiltroprticoLabel = uilabel(app.RightPanel);
            app.FiltroprticoLabel.Text = 'Filtro prático';
            app.JanelaLabel = uilabel(app.RightPanel);
            app.JanelaLabel.Text = 'Janela';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TempoTabP = uitab(app.TabGroup);
            app.TempoTabP.Title = 'Tempo';
            app.TempoPratico = uiaxes(app.TempoTabP);
            title(app.TempoPratico, '')
            xlabel(app.TempoPratico, 'Tempo (s)')
            ylabel(app.TempoPratico, 'Amplitude ')
            app.MagnitudeTab = uitab(app.TabGroup);
            app.MagnitudeTab.Title = 'Magnitude';
            app.MagnitudeFiltro = uiaxes(app.MagnitudeTab);
            title(app.MagnitudeFiltro, '')
            xlabel(app.MagnitudeFiltro, 'Frequência (Hz)')
            ylabel(app.MagnitudeFiltro, 'Amplitude (dB)')
            app.FaseTab = uitab(app.TabGroup);
            app.FaseTab.Title = 'Fase';
            app.FaseFiltro = uiaxes(app.FaseTab);
            title(app.FaseFiltro, '')
            xlabel(app.FaseFiltro, 'X')
            ylabel(app.FaseFiltro, 'Y')
            app.ResultadoLabel = uilabel(app.RightPanel);
            app.ResultadoLabel.Text = 'Resultado';
            app.LarguradajanelaLabel = uilabel(app.RightPanel);
            app.LarguradajanelaLabel.Text = 'Largura da janela';
            app.larguraJanela = uieditfield(app.RightPanel, 'numeric');
            app.larguraJanela.Editable = 'off';
            app.JanelautilizadaLabel = uilabel(app.RightPanel);
            app.JanelautilizadaLabel.Text = 'Janela utilizada';
            app.JanelaUtilizadaText = uieditfield(app.RightPanel, 'text');
            app.JanelaUtilizadaText.Editable = 'off';
            app.CoeficientesdofiltroLabel = uilabel(app.RightPanel);
            app.CoeficientesdofiltroLabel.Text = 'Coeficientes do filtro';
            app.OrdemEditFieldLabel = uilabel(app.RightPanel);
            app.OrdemEditFieldLabel.Text = 'Ordem do filtro';
            app.ordemFiltro = uieditfield(app.RightPanel, 'numeric');
            app.ordemFiltro.Editable = 'off';
            app.TabGroup_2 = uitabgroup(app.RightPanel);
            app.MagnitudeTab_2 = uitab(app.TabGroup_2);
            app.MagnitudeTab_2.Title = 'Magnitude';
            app.MagnitudeJanela = uiaxes(app.MagnitudeTab_2);
            title(app.MagnitudeJanela, '')
            xlabel(app.MagnitudeJanela, 'Frequência (Hz)')
            ylabel(app.MagnitudeJanela, 'Amplitude (dB)')
            app.FaseTab_2 = uitab(app.TabGroup_2);
            app.FaseTab_2.Title = 'Fase';
            app.FaseJanela = uiaxes(app.FaseTab_2);
            title(app.FaseJanela, '')
            xlabel(app.FaseJanela, 'X')
            ylabel(app.FaseJanela, 'Y')
            app.coefsFiltro = uitextarea(app.RightPanel);
            app.coefsFiltro.Editable = 'off';
            app.HTML = uihtml(app.RightPanel);
            app.HTML.HTMLSource = '<hr style="height:1.5px;border-width:0;color:rgb(240,140,201);background-color:rgb(240,140,201)"></hr>';
            app.HTML_2 = uihtml(app.RightPanel);
            app.HTML_2.HTMLSource = '<hr style="height:1.5px;border-width:0;color:rgb(240,140,201);background-color:rgb(240,140,201)"></hr>';
            app.HTML_3 = uihtml(app.RightPanel);
            app.HTML_3.HTMLSource = '<hr style="height:1.5px;border-width:0;color:rgb(240,140,201);background-color:rgb(240,140,201)"></hr>';
            app.TabGroup_3 = uitabgroup(app.RightPanel);
            app.TempoTab = uitab(app.TabGroup_3);
            app.TempoTab.Title = 'Tempo';
            app.TempoIdeal = uiaxes(app.TempoTab);
            title(app.TempoIdeal, '')
            xlabel(app.TempoIdeal, 'Tempo (s)')
            ylabel(app.TempoIdeal, 'Amplitude')
            app.MagnitudeTab_3 = uitab(app.TabGroup_3);
            app.MagnitudeTab_3.Title = 'Magnitude';
            app.MagnitudeIdeal = uiaxes(app.MagnitudeTab_3);
            title(app.MagnitudeIdeal, '')
            xlabel(app.MagnitudeIdeal, 'Frequência (Hz)')
            ylabel(app.MagnitudeIdeal, 'Amplitude (dB)')
            app.FaseTab_3 = uitab(app.TabGroup_3);
            app.FaseTab_3.Title = 'Fase';
            app.FaseIdeal = uiaxes(app.FaseTab_3);
            title(app.FaseIdeal, '')
            xlabel(app.FaseIdeal, 'X')
            ylabel(app.FaseIdeal, 'Y')
            app.FiltroidealLabel = uilabel(app.RightPanel);
            app.FiltroidealLabel.Text = 'Filtro ideal';
            app.HTML_4 = uihtml(app.RightPanel);
            app.HTML_4.HTMLSource = '<hr style="height:1.5px;border-width:0;color:rgb(240,140,201);background-color:rgb(240,140,201)"></hr>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end